classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        SaveButton                matlab.ui.control.Button
        ViewgainscheduleCheckBox  matlab.ui.control.CheckBox
        BincountSlider            matlab.ui.control.Slider
        BincountSliderLabel       matlab.ui.control.Label
        SmoothSlider              matlab.ui.control.Slider
        SmoothSliderLabel         matlab.ui.control.Label
        ComputeButton             matlab.ui.control.Button
        FilenameLabel             matlab.ui.control.Label
        FilenameEditField         matlab.ui.control.EditField
        ImportButton              matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Qpumping % Store pumping data
        Qleaking % Store leaking data
        ln_dP_leak % Store ln pressure devation with time for determining leak rate constant

        P_atm; % atmospheric pressure which can be calcualted from state 0 data

        % dQleaking_dP % In our control scheme, we need change in rate of leaking wrt change in pressure

        numBins = 20 % Store the number of bins allocated for gain scheduling. Default val set here.
        gain_schedule % Store all gain schedules

        gain_scale_factor = 50 % scaling to improve tuning

        rise_time = 0.05; % robustness of system. How fast it takes to rise from 10% to 90% of setpoint

        smooth_alpha  = 0 % smoothing value for complementry filter
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.SaveButton.Enable = 'off';
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.BackgroundColor = [0.68, 0.29, 0.88];
            app.BincountSlider.Enable = 'off';
            app.BincountSliderLabel.Enable = 'off';
            app.ViewgainscheduleCheckBox.Enable = 'off';
            app.ViewgainscheduleCheckBox.Value = false;
            app.UIAxes_2.Visible = 'on';
            
            
            % Load data from the function

            try
                % Validate if the file field is empty
                if isempty(app.FilenameEditField.Value)
                    uialert(app.UIFigure, 'Please provide a file name or select a file.', 'Error');
                    return;
                end
        
                % Validate if the file exists
                if ~isfile(app.FilenameEditField.Value)
                    uialert(app.UIFigure, 'The specified file does not exist. Please check the file path.', 'Error');
                    return;
                end

                [app.Qpumping, app.Qleaking, app.ln_dP_leak, app.P_atm] = create_data(app.FilenameEditField.Value);

                
                % Save data to workspace for viewing
                assignin('base', 'Qpumping', app.Qpumping);
                assignin('base', 'Qleaking', app.Qleaking);

                % apply smoothing
                app.ln_dP_leak = complementary_filter(app.ln_dP_leak, app.smooth_alpha);
                app.Qpumping = complementary_filter(app.Qpumping, app.smooth_alpha);
                app.Qleaking = complementary_filter(app.Qleaking, app.smooth_alpha);
                
                % Plot Pumping Data on UIAxes
                plot(app.UIAxes, app.Qpumping(:, 2), app.Qpumping(:, 1), '-', 'Color', [0.18, 0.03, 0.25], 'LineWidth', 1.5);
                title(app.UIAxes, 'Pumping Rate vs Operating Pressure');
                xlabel(app.UIAxes, 'Operating Pressure (Pa)');
                ylabel(app.UIAxes, 'Pumping Rate (Pa/s)');
                grid(app.UIAxes, 'on');
                axis(app.UIAxes, 'auto');
            
                % Plot Leaking Data on UIAxes_2
                plot(app.UIAxes_2, app.Qleaking(:, 2), app.Qleaking(:, 1), '-', 'Color', [0.18, 0.03, 0.25], 'LineWidth', 1.5);

                title(app.UIAxes_2, 'Leaking Rate vs Operating Pressure');
                xlabel(app.UIAxes_2, 'Operating Pressure (Pa)');
                ylabel(app.UIAxes_2, 'Leaking Rate (Pa/s)');
                grid(app.UIAxes_2, 'on');
                axis(app.UIAxes_2, 'auto');
                
                app.LoadButton.BackgroundColor = [0.92,0.83,0.97];
                app.ComputeButton.Enable = 'on';
                app.BincountSlider.Enable = 'on';
                app.BincountSliderLabel.Enable = 'on';


            catch ME
                % Display a user-friendly error message
                uialert(app.UIFigure, sprintf('An error occurred:\n%s', ME.message), 'Error');
            end
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            app.ComputeButton.Enable = 'off';
            app.ViewgainscheduleCheckBox.Enable = 'off';
            app.ViewgainscheduleCheckBox.Value = false;
            app.BincountSlider.Enable = 'off';
            app.BincountSliderLabel.Enable = 'off';
            app.SaveButton.Enable = 'off';
            
            filename = uigetfile('*.csv', 'Select a CSV File');

            % Check if the user selected a file or cancelled the dialog
            if isequal(filename, 0)
                % The user pressed cancel
                uialert(app.UIFigure, 'No file selected. Please select a valid CSV file.', 'File Selection');
            else
                % Ensure the filename is a string scalar or character vector
                if ischar(filename) || isstring(filename)
                    % Construct the full file path
                    % fullPath = fullfile(path, filename);
                    
                    % Update the FilenameEditField value
                    app.FilenameEditField.Value = filename; % Ensure it is a valid string or character vector

                    figure(app.UIFigure);
                    app.LoadButton.Enable = 'on';
                    app.LoadButton.BackgroundColor = [0.68, 0.29, 0.88];
                    app.SmoothSlider.Enable = 'on';
                    app.SmoothSliderLabel.Enable = 'on';

                    % Clear the second plot (UIAxes_2)
                    cla(app.UIAxes);
                    if ~isempty(findobj(app.UIAxes, 'Type', 'Legend')) % Check if a legend exists
                        delete(findobj(app.UIAxes, 'Type', 'Legend')); % Remove the legend
                    end
                    title(app.UIAxes, '');
                    xlabel(app.UIAxes, '');
                    ylabel(app.UIAxes, '');
    
                    % Clear the second plot (UIAxes_2)
                    cla(app.UIAxes_2);
                    if ~isempty(findobj(app.UIAxes_2, 'Type', 'Legend')) % Check if a legend exists
                        delete(findobj(app.UIAxes_2, 'Type', 'Legend')); % Remove the legend
                    end
                    title(app.UIAxes_2, '');
                    xlabel(app.UIAxes_2, '');
                    ylabel(app.UIAxes_2, '');

                else
                    % Handle unexpected types gracefully
                    uialert(app.UIFigure, 'Invalid file type selected. Please try again.', 'Error');
                end
            end

            
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            % value = app.FilenameEditField.Value;
            
        end

        % Value changed function: SmoothSlider
        function SmoothSliderValueChanged(app, event)
            value = app.SmoothSlider.Value;

            app.smooth_alpha = max(0, min(0.99, value));

            
            app.LoadButton.BackgroundColor = [0.68, 0.29, 0.88];
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            progress_bar = uiprogressdlg(app.UIFigure, 'Title', 'Progress', 'Message', 'Initializing...');

            app.ComputeButton.BackgroundColor = [0.92,0.83,0.97];
            
            % app.dQleaking_dP = complementary_filter(app.dQleaking_dP, app.smooth_alpha);


            data_binned = bin_data(app.Qleaking, app.Qpumping, app.numBins);
            app.gain_schedule = zeros(app.numBins, 4); % store PID gains and operating point

            % k_leak = get_leak_constant(app.ln_dP_leak); % calculate leak constant

            desired_bandwidth = 0.35/app.rise_time;

            Qpump_at_P_0 = data_binned(:, 2);
            Qleak_at_P_0 = data_binned(:, 1);
            P_0 = data_binned(:, 3);

            for i=1:(app.numBins)
                [Kp, Ki, Kd] = PID_control_gains(Qpump_at_P_0(i), Qleak_at_P_0(i), app.P_atm, P_0(i), app.gain_scale_factor, desired_bandwidth);
                app.gain_schedule(i, :) = round([Kp, Ki, Kd, P_0(i)], 3);

                % Progress bar stuff
                progress_bar.Value = (i/(app.numBins)); % Set progress to 50%
                progress_bar.Message = 'Processing data...'; % Update the message
                % pause(0.01); % Pause for short period to give progress more legitttness
            end

            assignin('base', 'gain_schedule', app.gain_schedule);
            
            app.ViewgainscheduleCheckBox.Enable = 'on';
            app.SaveButton.Enable = 'on';
            
            % Trigger the callback function
            app.ViewgainscheduleCheckBox.Value = true;
            ViewgainscheduleCheckBoxValueChanged(app, struct());


        end

        % Value changed function: BincountSlider
        function BincountSliderValueChanged(app, event)
            app.BincountSlider.Value = round(app.BincountSlider.Value); % round slider to discrete value
            app.numBins = app.BincountSlider.Value;
            app.ComputeButton.BackgroundColor = [0.68, 0.29, 0.88];
        end

        % Value changed function: ViewgainscheduleCheckBox
        function ViewgainscheduleCheckBoxValueChanged(app, event)
            value = app.ViewgainscheduleCheckBox.Value;

            if (value == true)
                % Extract gain schedule data
                Kp = app.gain_schedule(:, 1);
                Ki = app.gain_schedule(:, 2);
                Kd = app.gain_schedule(:, 3);
                P_0 = app.gain_schedule(:, 4);
            
                % Plot gains vs. P_0 on UIAxes
                plot(app.UIAxes, P_0, Kp, '-', 'DisplayName', 'Kp', 'Color', [0.85, 0.33, 0.10], 'LineWidth', 2);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, P_0, Ki, '-', 'DisplayName', 'Ki', 'Color', [0.10, 0.75, 0.25], 'LineWidth', 2);
                plot(app.UIAxes, P_0, Kd, '-', 'DisplayName', 'Kd', 'Color', [0.10, 0.25, 0.85], 'LineWidth', 2);
            
                % Customize the first plot
                title(app.UIAxes, 'PID Gains vs Operating Pressure');
                xlabel(app.UIAxes, 'Operating Pressure (P_0) (Pa)');
                ylabel(app.UIAxes, 'Gains');
                legend(app.UIAxes, 'show');
                grid(app.UIAxes, 'on');
                hold(app.UIAxes, 'off');
                axis(app.UIAxes, 'auto');
            
                % Clear the second plot (UIAxes_2)
                cla(app.UIAxes_2);
                title(app.UIAxes_2, '');
                xlabel(app.UIAxes_2, '');
                ylabel(app.UIAxes_2, '');
                app.UIAxes_2.Visible = 'off';
            
            else
                % Plot Pumping Data on UIAxes
                plot(app.UIAxes, app.Qpumping(:, 2), app.Qpumping(:, 1), '-', 'Color', [0.18, 0.03, 0.25], 'LineWidth', 1.5);
                title(app.UIAxes, 'Pumping Rate vs Operating Pressure');
                xlabel(app.UIAxes, 'Operating Pressure (Pa)');
                ylabel(app.UIAxes, 'Pumping Rate (Pa/s)');
                grid(app.UIAxes, 'on');
                axis(app.UIAxes, 'auto');

                % Plot Leaking Data on UIAxes_2

                app.UIAxes_2.Visible = 'on';
                plot(app.UIAxes_2, app.Qleaking(:, 2), app.Qleaking(:, 1), '-', 'Color', [0.18, 0.03, 0.25], 'LineWidth', 1.5);

                title(app.UIAxes_2, 'Leaking Rate vs Operating Pressure');
                xlabel(app.UIAxes_2, 'Operating Pressure (Pa)');
                ylabel(app.UIAxes_2, 'Leaking Rate (Pa/s)');
                grid(app.UIAxes_2, 'on');
                axis(app.UIAxes_2, 'auto');
            end
 
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Open a file save dialog for the user to specify location and file name
            [fileName, filePath] = uiputfile('*.csv', 'Save Gain Schedule As');
            
            % Check if the user canceled the dialog
            if isequal(fileName, 0) || isequal(filePath, 0)
                return;
            end
            
            % Construct the full file path
            fullFileName = fullfile(filePath, fileName);
            
            % Save the gain schedule matrix to the selected CSV file
            try
                writematrix(app.gain_schedule, fullFileName);
                uialert(app.UIFigure, ['Gain schedule saved successfully to ', fullFileName], 'Save Successful', 'Icon', 'success');
            catch ME
                % Display error message if saving fails
                uialert(app.UIFigure, ['Error saving gain schedule: ', ME.message], 'Save Error', 'Icon', 'error');
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.ImportButton = uibutton(app.LeftPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.FilenameEditField = uieditfield(app.LeftPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.FilenameLabel = uilabel(app.LeftPanel);
            app.FilenameLabel.Text = 'Filename:';
            app.ComputeButton = uibutton(app.LeftPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Enable = 'off';
            app.ComputeButton.Text = 'Compute';
            app.SmoothSliderLabel = uilabel(app.LeftPanel);
            app.SmoothSliderLabel.Enable = 'off';
            app.SmoothSliderLabel.Text = 'Smooth';
            app.SmoothSlider = uislider(app.LeftPanel);
            app.SmoothSlider.Limits = [0 1];
            app.SmoothSlider.ValueChangedFcn = createCallbackFcn(app, @SmoothSliderValueChanged, true);
            app.SmoothSlider.Enable = 'off';
            app.BincountSliderLabel = uilabel(app.LeftPanel);
            app.BincountSliderLabel.Enable = 'off';
            app.BincountSliderLabel.Text = 'Bin count';
            app.BincountSlider = uislider(app.LeftPanel);
            app.BincountSlider.Limits = [10 100];
            app.BincountSlider.ValueChangedFcn = createCallbackFcn(app, @BincountSliderValueChanged, true);
            app.BincountSlider.Enable = 'off';
            app.BincountSlider.Value = 20;
            app.ViewgainscheduleCheckBox = uicheckbox(app.LeftPanel);
            app.ViewgainscheduleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ViewgainscheduleCheckBoxValueChanged, true);
            app.ViewgainscheduleCheckBox.Enable = 'off';
            app.ViewgainscheduleCheckBox.Text = 'View gain schedule';
            app.SaveButton = uibutton(app.LeftPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end